classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectFileButton                matlab.ui.control.Button
        ExampleofexpectedspreadsheetxlsxcolumnnamedatespotfutureLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        FigureContentDropDownLabel      matlab.ui.control.Label
        FigureContentDropDown           matlab.ui.control.DropDown
        SelectedFileEditFieldLabel      matlab.ui.control.Label
        SelectedFileEditField           matlab.ui.control.EditField
        ARIMApSpinnerLabel              matlab.ui.control.Label
        ARIMApSpinner                   matlab.ui.control.Spinner
        ARIMAqSpinnerLabel              matlab.ui.control.Label
        ARIMAqSpinner                   matlab.ui.control.Spinner
        CalculateButton                 matlab.ui.control.Button
        DataprocessingandorderingLabel  matlab.ui.control.Label
        CalculatingHongCausalityLabel   matlab.ui.control.Label
        MSpinnerLabel                   matlab.ui.control.Label
        MSpinner                        matlab.ui.control.Spinner
        CausalityfromfuturetospotEditFieldLabel  matlab.ui.control.Label
        CausalityfromfuturetospotEditField  matlab.ui.control.NumericEditField
        CausalityfromspottofutureEditFieldLabel  matlab.ui.control.Label
        CausalityfromspottofutureEditField  matlab.ui.control.NumericEditField
        CausalityofbinarydirectionEditFieldLabel  matlab.ui.control.Label
        CausalityofbinarydirectionEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        spot_rtn
        future_rtn
    end
    
    methods (Access = public)
        
        function y = movWindow(~, x, M)
            [n,m] = size(x);
            if m == 1
                assert(n > M,'input shorter than length of window');
                x = x';
                m = n;
            else
                assert(n == 1,'input not a vector');
                assert(m > M,'input shorter than length of window');
            end
            x = repmat(x,m,1);
            y = zeros(m,M);
            for i=1:m
                y(i,max(M+1-i,1):M) = x(i,max(i-M+1,1):i);
            end
            y = y(M:end,:);
        end
                
        function rho_j = rho(~, u, v, j)
            T = length(u);
            assert(T == length(v),'length of u_t & v_t is different');
            c_u0 = std(u, 1);
            c_v0 = std(v, 1);
            rho_j = zeros(1, length(j));
            for jj = 1:length(j)
                if j(jj) >=0
                    t = j(jj) + 1 : T;
                    c_j = sum( (u(t)-mean(u)).*(v(t-j(jj))-mean(v)) )/T;
                else
                    t = -j(jj) + 1 : T;
                    c_j = sum( (u(t+j(jj))-mean(u)).*(v(t)-mean(v)) )/T;
                end
                rho_j(jj) = c_j ./ sqrt(c_u0*c_v0);
            end
        end
        
        function k_ = k(~, x)
            k_ = sin(pi*x) ./ (pi*x);
            k_(isnan(k_)) = 1;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            filename = uigetfile({'*.xlsx'});
            try
                myfile = readtable(filename);
                app.SelectedFileEditField.Value = filename;
            catch
                myfile = struct("spot",randn(5,1),"date",randn(5,1),"future",randn(5,1));
                app.SelectedFileEditField.Value = "No File";
            end
            app.spot_rtn = myfile.spot(2:end) ./ myfile.spot(1:end-1) - 1;
            app.future_rtn = myfile.future(2:end) ./ myfile.spot(1:end-1) - 1;
        end

        % Value changed function: FigureContentDropDown
        function FigureContentDropDownValueChanged(app, event)
            value = app.FigureContentDropDown.Value;
            if value == "spot ACF"
                autocorr(app.UIAxes, app.spot_rtn);
            elseif value == "spot PACF"
                parcorr(app.UIAxes, app.spot_rtn);
            elseif value == "future ACF"
                autocorr(app.UIAxes, app.future_rtn);
            elseif value == "future PACF"
                parcorr(app.UIAxes, app.future_rtn);
            else
                cla(app.UIAxes);
            end
            app.UIAxes.YLim = [-0.4, 0.4];
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % volatility
            p = app.ARIMApSpinner.Value;
            q = app.ARIMAqSpinner.Value;
            M = app.MSpinner.Value;
            model = arima(p,0,q);
            est_spot = estimate(model,app.spot_rtn);
            res_spot = infer(est_spot,app.spot_rtn);
            est_future = estimate(model,app.future_rtn);
            res_future = infer(est_future,app.future_rtn);
            assert(length(res_spot) == length(res_future), "Length of spot and future is different.")
            ep_s_t = normalize(res_spot(M:end));
            ep_f_t = normalize(res_future(M:end));
            h_s_t = std(app.movWindow(app.spot_rtn,M),[],2).*sqrt(252);
            h_f_t = std(app.movWindow(app.future_rtn,M),[],2).*sqrt(252);
            u = ep_s_t.^2./h_s_t-1;
            v = ep_f_t.^2./h_f_t-1;
            T = length(u);
            % future -> spot
            j = 1:T-1;
            C_1T = sum((1-j/T).*app.k(j/M).^2);
            D_1T = sum((1-j/T).*(1-(j+1)/T).*app.k(j/M).^4);
            Q_1 = T * (sum(app.k(j/M).^2.*app.rho(u, v, j).^2) - C_1T) ./ sqrt(2*D_1T);
            app.CausalityfromfuturetospotEditField.Value = Q_1;
            % spot -> future
            Q_1_inv = T * (sum(app.k(j/M).^2.*app.rho(v, u, j).^2) - C_1T) ./ sqrt(2*D_1T);
            app.CausalityfromspottofutureEditField.Value = Q_1_inv;
            % bi-direction
            j = 1-T:T-1;
            C_2T = sum((1-abs(j)/T).*app.k(abs(j)/M).^2);
            D_2T = sum((1-abs(j)/T).*(1-(abs(j)+1)/T).*app.k(j/M).^4);
            Q_2 = T * (sum(app.k(j/M).^2.*app.rho(u, v, j).^2) - C_2T) ./ sqrt(2*D_2T);
            app.CausalityofbinarydirectionEditField.Value = Q_2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.ExampleofexpectedspreadsheetxlsxcolumnnamedatespotfutureLabel = uilabel(app.UIFigure);
            app.ExampleofexpectedspreadsheetxlsxcolumnnamedatespotfutureLabel.Text = {'Example of expected spreadsheet:'; ''; '*.xlsx, column name: date, spot, future'; '    date: from earlier to later'; '    spot: close price of the spot'; '    future: close price of the future'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.FigureContentDropDownLabel = uilabel(app.UIFigure);
            app.FigureContentDropDownLabel.Text = 'Figure Content';
            app.FigureContentDropDown = uidropdown(app.UIFigure);
            app.FigureContentDropDown.Items = {'clear', 'spot ACF', 'spot PACF', 'future ACF', 'future PACF'};
            app.FigureContentDropDown.ValueChangedFcn = createCallbackFcn(app, @FigureContentDropDownValueChanged, true);
            app.FigureContentDropDown.Value = 'clear';
            app.SelectedFileEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedFileEditField.Editable = 'off';
            app.ARIMApSpinnerLabel = uilabel(app.UIFigure);
            app.ARIMApSpinnerLabel.Text = 'ARIMA p';
            app.ARIMApSpinner = uispinner(app.UIFigure);
            app.ARIMApSpinner.Limits = [1 20];
            app.ARIMApSpinner.Value = 1;
            app.ARIMAqSpinnerLabel = uilabel(app.UIFigure);
            app.ARIMAqSpinnerLabel.Text = 'ARIMA q';
            app.ARIMAqSpinner = uispinner(app.UIFigure);
            app.ARIMAqSpinner.Limits = [1 20];
            app.ARIMAqSpinner.Value = 1;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DataprocessingandorderingLabel = uilabel(app.UIFigure);
            app.DataprocessingandorderingLabel.Text = 'Data processing and ordering';
            app.CalculatingHongCausalityLabel = uilabel(app.UIFigure);
            app.CalculatingHongCausalityLabel.Text = 'Calculating Hong Causality';
            app.MSpinnerLabel = uilabel(app.UIFigure);
            app.MSpinnerLabel.Text = 'M';
            app.MSpinner = uispinner(app.UIFigure);
            app.MSpinner.Step = 5;
            app.MSpinner.Limits = [5 100];
            app.MSpinner.Value = 5;
            app.CausalityfromfuturetospotEditFieldLabel = uilabel(app.UIFigure);
            app.CausalityfromfuturetospotEditFieldLabel.Text = 'Causality from future to spot';
            app.CausalityfromfuturetospotEditField = uieditfield(app.UIFigure, 'numeric');
            app.CausalityfromfuturetospotEditField.Editable = 'off';
            app.CausalityfromspottofutureEditFieldLabel = uilabel(app.UIFigure);
            app.CausalityfromspottofutureEditFieldLabel.Text = 'Causality from spot to future';
            app.CausalityfromspottofutureEditField = uieditfield(app.UIFigure, 'numeric');
            app.CausalityfromspottofutureEditField.Editable = 'off';
            app.CausalityofbinarydirectionEditFieldLabel = uilabel(app.UIFigure);
            app.CausalityofbinarydirectionEditFieldLabel.Text = 'Causality of binary direction';
            app.CausalityofbinarydirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CausalityofbinarydirectionEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end